classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SendButton               matlab.ui.control.Button
        UITableJointRotations    matlab.ui.control.Table
        SolveButton              matlab.ui.control.Button
        zEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        ButtonExecuteCommands    matlab.ui.control.Button
        TextAreaScripting        matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        DisconnectButton         matlab.ui.control.Button
        ConnectionLabel          matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        StartButton              matlab.ui.control.Button
        ReHomeButton             matlab.ui.control.Button
        TabGroupJointJogCartMov  matlab.ui.container.TabGroup
        JointJogTab              matlab.ui.container.Tab
        ButtonGroupJogJointdeg   matlab.ui.container.ButtonGroup
        JogJoint30deg            matlab.ui.control.ToggleButton
        JogJoint15deg            matlab.ui.control.ToggleButton
        JogJoint5deg             matlab.ui.control.ToggleButton
        ZLabelnegJogJoint        matlab.ui.control.Label
        ZLabelposJogJoint        matlab.ui.control.Label
        JogJointCW               matlab.ui.control.Button
        JogJointCCW              matlab.ui.control.Button
        ButtonGroupJogJoint      matlab.ui.container.ButtonGroup
        J6Button                 matlab.ui.control.ToggleButton
        J5Button                 matlab.ui.control.ToggleButton
        J4Button                 matlab.ui.control.ToggleButton
        J3Button                 matlab.ui.control.ToggleButton
        J2Button                 matlab.ui.control.ToggleButton
        J1Button                 matlab.ui.control.ToggleButton
        CartMoveTab              matlab.ui.container.Tab
        ButtonGroupCartMov       matlab.ui.container.ButtonGroup
        CartMoveMax              matlab.ui.control.ToggleButton
        CartMoveMed              matlab.ui.control.ToggleButton
        CartMoveMin              matlab.ui.control.ToggleButton
        TabGroupTransOrient      matlab.ui.container.TabGroup
        TranslationTab           matlab.ui.container.Tab
        ZLabelposCartMove        matlab.ui.control.Label
        CartMoveZpos             matlab.ui.control.Button
        ZLabelnegCartMove        matlab.ui.control.Label
        CartMoveZneg             matlab.ui.control.Button
        XLabelnegCartMove        matlab.ui.control.Label
        CartMoveXneg             matlab.ui.control.Button
        XLabelposCartMove        matlab.ui.control.Label
        CartMoveXpos             matlab.ui.control.Button
        YLabelposCartMove        matlab.ui.control.Label
        CartMoveYpos             matlab.ui.control.Button
        YLabelnegCartMove        matlab.ui.control.Label
        CartMoveYneg             matlab.ui.control.Button
        OrientationTab           matlab.ui.container.Tab
        ZLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientZneg       matlab.ui.control.Button
        ZLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientZpos       matlab.ui.control.Button
        XLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientXneg       matlab.ui.control.Button
        XLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientXpos       matlab.ui.control.Button
        YLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientYpos       matlab.ui.control.Button
        YLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientYneg       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        currconfig = [0 0 0 0 0 0]';
        currTrans = [0 0.1998 0.3340];
        currOrient = [0 pi/2 pi/2] % ZYX
        newconfig = [];
        controller = [];
        robot = [];
        canSend = true;
    end
    
    methods (Access = private)
        
        
        function q = jogJoints(app, mode)
            switch mode
                case "positive"
                    sign = 1;
                case "negative"
                    sign = -1;
            end

            amount = app.ButtonGroupJogJointdeg.SelectedObject;
            switch amount
                case app.JogJoint5deg
                    amount = deg2rad(5);
                case app.JogJoint15deg
                    amount = deg2rad(15);
                case app.JogJoint30deg
                    amount = deg2rad(30);
            end

            config = app.currconfig;
            joint = app.ButtonGroupJogJoint.SelectedObject;
            switch joint
                case app.J1Button
                    config = config + [amount 0 0 0 0 0]'.*sign;
                case app.J2Button
                    config = config + [0 amount 0 0 0 0]'.*sign;
                case app.J3Button
                    config = config + [0 0 amount 0 0 0]'.*sign;
                case app.J4Button
                    config = config + [0 0 0 amount 0 0]'.*sign;
                case app.J5Button
                    config = config + [0 0 0 0 amount 0]'.*sign;
                case app.J6Button
                    config = config + [0 0 0 0 0 amount]'.*sign;
            end
            q = config;
        end
        
        % calc inverse kinematics (with joint limits hopefully) from trans
        % vector, orientation through ZYX
        % standard pos = [262.57 0 288.75]
        function qIK = calcIK(app, trans, orient)
            ee = app.robot.BodyNames{end};

            gIK = generalizedInverseKinematics("RigidBodyTree", app.robot, ...
                'ConstraintInputs', {'position', 'orientation', 'jointbounds'});

            % constraints
            posTgt = constraintPositionTarget(ee);
            posTgt.TargetPosition = trans; % trans vector, z.B. [0 0.2 0.2]
            posTgt.Weights = 1;
            
            oriTgt = constraintOrientationTarget(ee);
            oriTgt.TargetOrientation = eul2quat(orient,"ZYX"); % ZYX rotation, .z.B. [0 pi/2 pi/2]
            oriTgt.Weights = 1;
            
            jointBounds = constraintJointBounds(app.robot);
            [config, info] = gIK(app.currconfig , posTgt, oriTgt, jointBounds);

            qIK = config;
        end
        
        function sendJointRotation(app, q, type)
            if type == "RAD"
                qDEG = rad2deg(q);
            elseif type == "DEG"
                qDEG = q;
            else
                error("Invalid unit! Use RAD or DEG");
            end

            % multiply by 100 to not loose two decimals
            qInt16 = int16(qDEG.*100);

            % send command type
            write(app.controller, uint8('J'), "uint8");
            % send array of angles to controller
            write(app.controller, qInt16, "int16");
            app.canSend = false;

            % Wait for robot acknowledgement
            timeout = 15; % seconds
            tStart = tic;
            response = "";

            while toc(tStart) < timeout
                if app.controller.NumBytesAvailable > 0
                    response = char(read(app.controller, 1, 'uint8'));
                    if response == 'D'
                        app.canSend = true;
                        warning("Acknowledged!");
                        return;
                    end
                end
                pause(0.05);
            end
            warning("No acknowledgement from robot!")
        end
        
        function isValid = isValidJointPosition(app, q)
            n = numel(homeConfiguration(app.robot));
            lower = zeros(n,1);
            upper = zeros(n,1);

            for i = 1:n
                joint = app.robot.Bodies{i+1}.Joint;
                if strcmp(joint.Type, 'revolute') || strcmp(joint.Type, 'prismatic')
                    limits = joint.PositionLimits;
                    lower(i) = limits(1);
                    upper(i) = limits(2);
                else
                    lower(i) = -inf;
                    upper(i) = inf;
                end
            end
                        
            isValid = all(q >= lower) && all(q <= upper);
        end

        
        function q = cartMove(app, axis, mode)

            switch mode
                case "positive"
                    sign = 1;
                case "negative"
                    sign = -1;
                otherwise
                    error("Invalid Direction! Choose positive or negative!");
            end

            switch app.ButtonGroupCartMov.SelectedObject
                case app.CartMoveMax
                    stepTrans = 0.1;
                    stepRotDEG = 45;
                case app.CartMoveMed
                    stepTrans = 0.02;
                    stepRotDEG = 15;
                case app.CartMoveMin
                    stepTrans = 0.005;
                    stepRotDEG = 5;
            end
            stepRot = deg2rad(stepRotDEG);

            newTrans = app.currTrans;
            newOrient = app.currOrient;
            
            if app.TabGroupTransOrient.SelectedTab == app.TranslationTab
                % Translation Mode
                switch axis
                    case "X"
                        newTrans = app.currTrans + sign * [stepTrans 0 0];
                    case "Y"
                        newTrans = app.currTrans + sign * [0 stepTrans 0];
                    case "Z"
                        newTrans = app.currTrans + sign * [0 0 stepTrans];
                    otherwise
                        error("Invalid Axis! Choose X, Y or Z!");
                end
            elseif app.TabGroupTransOrient.SelectedTab == app.OrientationTab
                % Rotation Mode
                switch axis
                    case "X" % ZYX rotation
                        newOrient = app.currOrient + sign * [0 0 stepRot];
                    case "Y"
                        newOrient = app.currOrient + sign * [0 stepRot 0];
                    case "Z"
                        newOrient = app.currOrient + sign * [stepRot 0 0];
                    otherwise
                        error("Invalid Axis! Choose X, Y or Z!");
                end
            else
                error("Invalid tab selection!");
            end

            q = calcIK(app, newTrans, newOrient);
        end
        
        function [position, orientation] = getEEPose(app, q)
            T = getTransform(app.robot, q, app.robot.BodyNames{end});

            position = tform2trvec(T); % [X Y Z]
            orientation = tform2eul(T); % [Z Y X]
        end
        
        function cartMoveSend(app, axis, mode)
            if ~app.canSend
                warning("Robot is still moving. Wait for it to finish.")
                return;
            end

            qIK = cartMove(app, axis, mode) % DES NO WECKTIA YALLA

            sendJointRotation(app, qIK, "RAD");

            if app.canSend
                app.currconfig = qIK;
                [newTrans, newOrient] = getEEPose(app, app.currconfig);
                % Set current transform according to new joint angles
                app.currTrans = newTrans;
                app.currOrient = newOrient;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = importrobot("PAROL6.urdf", 'DataFormat','column');
            %plotRigidBodyTreeFrames(app.robot, app.currconfig, app.UIAxes, 0.08);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            targetPos = [app.xEditField.Value;
                         app.yEditField.Value;
                         app.zEditField.Value];

            targetRot = [0 pi/2 pi/2];

            qIK = calcIK(app, targetPos, targetRot);            
            app.currconfig = qIK;

            %cla(app.UIAxes);
            %plotRigidBodyTreeFrames(app.robot, app.currconfig, app.UIAxes, 0.08);

            if ~isempty(app.controller)
                app.SendButton.Enable = "on";
            end

            qIKDEG = rad2deg(qIK);
            app.UITableJointRotations.Data = {'J1', qIK(1), qIKDEG(1)
                                'J2', qIK(2), qIKDEG(2)
                                'J3', qIK(3), qIKDEG(3)
                                'J4', qIK(4), qIKDEG(4)
                                'J5', qIK(5), qIKDEG(5)
                                'J6', qIK(6), qIKDEG(6)};

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)

            success = sendJointRotation(app, app.currconfig, "RAD")
            if success
                [newTrans, newOrient] = getEEPose(app, app.currconfig);
    
                % Set current transform according to new joint angles
                app.currTrans = newTrans;
                app.currOrient = newOrient;
            end
        
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.controller)
                try
                    port = app.SerialPortDropDown.Value;
                    app.controller = serialport(port, 9600);
                    pause(2);
                    app.ConnectionLabel.Text = "Connected!";

                    app.ConnectButton.Enable = "off";
                    app.DisconnectButton.Enable = "on";
                    app.JogJointCCW.Enable = "on";
                    app.JogJointCW.Enable = "on";

                    if ~isempty(app.newconfig)
                        app.SendButton.Enable = "on";
                    end
                catch
                    app.ConnectionLabel.Text = "Unable to connect!";
                    app.controller = [];
                end
            else
                app.ConnectionLabel.Text = "Already connected!";
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if ~isempty(app.controller)
                app.controller = [];
                app.DisconnectButton.Enable = "off";
                app.ConnectButton.Enable = "on";
                app.SendButton.Enable = "off";
                app.JogJointCCW.Enable = "off";
                app.JogJointCW.Enable = "off";
                app.ConnectionLabel.Text = "Not Connected!";
            end
        end

        % Button pushed function: JogJointCW
        function JogJointCWButtonPushed(app, event)
            
            if ~app.canSend
                warning("Robot is still moving. Wait for it to finish.");
                return;
            end
        
            q = jogJoints(app, "negative");
        
            if isValidJointPosition(app, q)
                sendJointRotation(app, q, "RAD");
                
                % Only update internal state *after* successful execution
                if app.canSend
                    1
                    app.currconfig = q;
                    [newTrans, newOrient] = getEEPose(app, app.currconfig);
                    app.currTrans = newTrans;
                    app.currOrient = newOrient;
                end
            else
                warning("Planned Joint Position out of bounds!");
            end
            
        end

        % Button pushed function: JogJointCCW
        function JogJointCCWButtonPushed(app, event)
            if ~app.canSend
                warning("Robot is still moving. Wait for it to finish.");
                return;
            end
        
            q = jogJoints(app, "positive");
        
            if isValidJointPosition(app, q)
                sendJointRotation(app, q, "RAD");
                
                % Only update internal state *after* successful execution
                if app.canSend
                    1
                    app.currconfig = q;
                    [newTrans, newOrient] = getEEPose(app, app.currconfig);
                    app.currTrans = newTrans;
                    app.currOrient = newOrient;
                end
            else
                warning("Planned Joint Position out of bounds!");
            end
        end

        % Button down function: TranslationTab
        function TranslationTabButtonDown(app, event)
            app.CartMoveMax.Text = "100 mm";
            app.CartMoveMed.Text = "20 mm";
            app.CartMoveMin.Text = "5 mm";
        end

        % Button down function: OrientationTab
        function OrientationTabButtonDown(app, event)
            app.CartMoveMax.Text = "45°";
            app.CartMoveMed.Text = "15°";
            app.CartMoveMin.Text = "5°";
        end

        % Button pushed function: CartMoveXneg
        function CartMoveXnegButtonPushed(app, event)
            cartMoveSend(app, "X", "negative");
        end

        % Button pushed function: CartMoveXpos
        function CartMoveXposButtonPushed(app, event)
            cartMoveSend(app, "X", "positive");
        end

        % Button pushed function: CartMoveYneg
        function CartMoveYnegButtonPushed(app, event)
            cartMoveSend(app, "Y", "negative");
        end

        % Button pushed function: CartMoveYpos
        function CartMoveYposButtonPushed(app, event)
            cartMoveSend(app, "Y", "positive");
        end

        % Button pushed function: CartMoveZneg
        function CartMoveZnegButtonPushed(app, event)
            cartMoveSend(app, "Z", "negative");
        end

        % Button pushed function: CartMoveZpos
        function CartMoveZposButtonPushed(app, event)
            cartMoveSend(app, "Z", "positive");
        end

        % Button pushed function: CartMoveOrientXneg
        function CartMoveOrientXnegButtonPushed(app, event)
            cartMoveSend(app, "X", "negative");
        end

        % Button pushed function: CartMoveOrientXpos
        function CartMoveOrientXposButtonPushed(app, event)
            cartMoveSend(app, "X", "positive");
        end

        % Button pushed function: CartMoveOrientYneg
        function CartMoveOrientYnegButtonPushed(app, event)
            cartMoveSend(app, "Y", "negative");
        end

        % Button pushed function: CartMoveOrientYpos
        function CartMoveOrientYposButtonPushed(app, event)
            cartMoveSend(app, "Y", "positive");
        end

        % Button pushed function: CartMoveOrientZneg
        function CartMoveOrientZnegButtonPushed(app, event)
            cartMoveSend(app, "Z", "negative");
        end

        % Button pushed function: CartMoveOrientZpos
        function CartMoveOrientZposButtonPushed(app, event)
            cartMoveSend(app, "Z", "positive");
        end

        % Button pushed function: ButtonExecuteCommands
        function ButtonExecuteCommandsPushed(app, event)
            commandsCellArray = app.TextAreaScripting.Value;
            for i = 1:length(commandsCellArray)
                line = commandsCellArray{i};
                if startsWith(line, "moveTo")
                    args = regexp(line, 'moveTo\((.*)\)', 'tokens');
                    if isempty(args)
                        error("Syntax error in line %d", i);
                    end
                    targetPose = str2double(strsplit(args{1}{1}, ','));
                    if numel(targetPose) ~= 6
                        error("moveTo requires 6 arguments");
                    end
                    targetPose

                elseif strcmp(line, "openGripper()")
                    %app.openGripper();
                    0
                elseif strcmp(line, "closeGripper()")
                    %app.closeGripper();
                    1
                elseif startsWith(line, "wait")
                    arg = regexp(line, 'wait\((.*)\)', 'tokens');
                    if isempty(arg)
                        error("Enter time value for wait(time) function");
                    end
                    waitTime = str2double(arg)

                elseif line == ""
                    continue; % allow empty lines
                else
                    error("Unknown command in line %d: %s", i, line);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITableJointRotations = uitable(app.LeftPanel);
            app.UITableJointRotations.ColumnName = {'Joint'; 'rad'; 'deg'};
            app.UITableJointRotations.RowName = {};
            app.SendButton = uibutton(app.LeftPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.CenterPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.CenterPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.CenterPanel);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.ItemsData = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.Value = 'COM1';
            app.ConnectionLabel = uilabel(app.CenterPanel);
            app.ConnectionLabel.Text = 'Not Connected!';
            app.DisconnectButton = uibutton(app.CenterPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.TextAreaLabel = uilabel(app.CenterPanel);
            app.TextAreaLabel.Text = '';
            app.TextAreaScripting = uitextarea(app.CenterPanel);
            app.ButtonExecuteCommands = uibutton(app.CenterPanel, 'push');
            app.ButtonExecuteCommands.ButtonPushedFcn = createCallbackFcn(app, @ButtonExecuteCommandsPushed, true);
            app.ButtonExecuteCommands.Text = 'Execute';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroupJointJogCartMov = uitabgroup(app.RightPanel);
            app.JointJogTab = uitab(app.TabGroupJointJogCartMov);
            app.JointJogTab.Title = 'Joint Jog';
            app.ButtonGroupJogJoint = uibuttongroup(app.JointJogTab);
            app.J1Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J1Button.Text = 'J1';
            app.J1Button.Value = true;
            app.J2Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J2Button.Text = 'J2';
            app.J3Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J3Button.Text = 'J3';
            app.J4Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J4Button.Text = 'J4';
            app.J5Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J5Button.Text = 'J5';
            app.J6Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J6Button.Text = 'J6';
            app.JogJointCCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCCW.ButtonPushedFcn = createCallbackFcn(app, @JogJointCCWButtonPushed, true);
            app.JogJointCCW.Enable = 'off';
            app.JogJointCCW.Text = '↺';
            app.JogJointCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCW.ButtonPushedFcn = createCallbackFcn(app, @JogJointCWButtonPushed, true);
            app.JogJointCW.Enable = 'off';
            app.JogJointCW.Text = '↻';
            app.ZLabelposJogJoint = uilabel(app.JointJogTab);
            app.ZLabelposJogJoint.Text = '+Z';
            app.ZLabelnegJogJoint = uilabel(app.JointJogTab);
            app.ZLabelnegJogJoint.Text = '-Z';
            app.ButtonGroupJogJointdeg = uibuttongroup(app.JointJogTab);
            app.JogJoint5deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint5deg.Text = '5°';
            app.JogJoint5deg.Value = true;
            app.JogJoint15deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint15deg.Text = '15°';
            app.JogJoint30deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint30deg.Text = '30°';
            app.CartMoveTab = uitab(app.TabGroupJointJogCartMov);
            app.CartMoveTab.Title = 'Cart Move';
            app.TabGroupTransOrient = uitabgroup(app.CartMoveTab);
            app.TranslationTab = uitab(app.TabGroupTransOrient);
            app.TranslationTab.Title = 'Translation';
            app.TranslationTab.ButtonDownFcn = createCallbackFcn(app, @TranslationTabButtonDown, true);
            app.CartMoveYneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveYneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveYnegButtonPushed, true);
            app.CartMoveYneg.Text = '←';
            app.YLabelnegCartMove = uilabel(app.TranslationTab);
            app.YLabelnegCartMove.Text = '-Y';
            app.CartMoveYpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveYpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveYposButtonPushed, true);
            app.CartMoveYpos.Text = '→';
            app.YLabelposCartMove = uilabel(app.TranslationTab);
            app.YLabelposCartMove.Text = '+Y';
            app.CartMoveXpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveXpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveXposButtonPushed, true);
            app.CartMoveXpos.Text = '↓';
            app.XLabelposCartMove = uilabel(app.TranslationTab);
            app.XLabelposCartMove.Text = '+X';
            app.CartMoveXneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveXneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveXnegButtonPushed, true);
            app.CartMoveXneg.Text = '↑';
            app.XLabelnegCartMove = uilabel(app.TranslationTab);
            app.XLabelnegCartMove.Text = '-X';
            app.CartMoveZneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveZneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveZnegButtonPushed, true);
            app.CartMoveZneg.Text = '↓';
            app.ZLabelnegCartMove = uilabel(app.TranslationTab);
            app.ZLabelnegCartMove.Text = '-Z';
            app.CartMoveZpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveZpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveZposButtonPushed, true);
            app.CartMoveZpos.Text = '↑';
            app.ZLabelposCartMove = uilabel(app.TranslationTab);
            app.ZLabelposCartMove.Text = '+Z';
            app.OrientationTab = uitab(app.TabGroupTransOrient);
            app.OrientationTab.Title = 'Orientation';
            app.OrientationTab.ButtonDownFcn = createCallbackFcn(app, @OrientationTabButtonDown, true);
            app.CartMoveOrientYneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientYneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientYnegButtonPushed, true);
            app.CartMoveOrientYneg.Text = '⤾';
            app.YLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.YLabelnegCartMoveOrient.Text = '-Y';
            app.CartMoveOrientYpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientYpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientYposButtonPushed, true);
            app.CartMoveOrientYpos.Text = '⤿';
            app.YLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.YLabelposCartMoveOrient.Text = '+Y';
            app.CartMoveOrientXpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientXpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientXposButtonPushed, true);
            app.CartMoveOrientXpos.Text = '⤺';
            app.XLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.XLabelposCartMoveOrient.Text = '+X';
            app.CartMoveOrientXneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientXneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientXnegButtonPushed, true);
            app.CartMoveOrientXneg.Text = '⤻';
            app.XLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.XLabelnegCartMoveOrient.Text = '-X';
            app.CartMoveOrientZpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientZpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientZposButtonPushed, true);
            app.CartMoveOrientZpos.Text = '⤹';
            app.ZLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.ZLabelposCartMoveOrient.Text = '+Z';
            app.CartMoveOrientZneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientZneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveOrientZnegButtonPushed, true);
            app.CartMoveOrientZneg.Text = '⤸';
            app.ZLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.ZLabelnegCartMoveOrient.Text = '-Z';
            app.ButtonGroupCartMov = uibuttongroup(app.CartMoveTab);
            app.CartMoveMin = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMoveMin.Text = '5 mm';
            app.CartMoveMin.Value = true;
            app.CartMoveMed = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMoveMed.Text = '20 mm';
            app.CartMoveMax = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMoveMax.Text = '100 mm';
            app.ReHomeButton = uibutton(app.RightPanel, 'push');
            app.ReHomeButton.Text = 'Re-Home';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end